classdef raghavan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        SwitchLabel              matlab.ui.control.Label
        Switch                   matlab.ui.control.Switch
        LaserLampLabel           matlab.ui.control.Label
        LaserLamp                matlab.ui.control.Lamp
        OpticallyReconfigurableUWBBandpassfilterLabel  matlab.ui.control.Label
        Button                   matlab.ui.control.Button
        C1EditFieldLabel         matlab.ui.control.Label
        C1EditField              matlab.ui.control.NumericEditField
        C2EditFieldLabel         matlab.ui.control.Label
        C2EditField              matlab.ui.control.NumericEditField
        C3EditFieldLabel         matlab.ui.control.Label
        C3EditField              matlab.ui.control.NumericEditField
        C4EditFieldLabel         matlab.ui.control.Label
        C4EditField              matlab.ui.control.NumericEditField
        R1EditFieldLabel         matlab.ui.control.Label
        R1EditField              matlab.ui.control.NumericEditField
        R2EditFieldLabel         matlab.ui.control.Label
        R2EditField              matlab.ui.control.NumericEditField
        R3EditFieldLabel         matlab.ui.control.Label
        R3EditField              matlab.ui.control.NumericEditField
        L1EditFieldLabel         matlab.ui.control.Label
        L1EditField              matlab.ui.control.NumericEditField
        L2EditFieldLabel         matlab.ui.control.Label
        L2EditField              matlab.ui.control.NumericEditField
        pFLabel                  matlab.ui.control.Label
        pFLabel_2                matlab.ui.control.Label
        pFLabel_3                matlab.ui.control.Label
        pFLabel_4                matlab.ui.control.Label
        OhmsLabel                matlab.ui.control.Label
        OhmsLabel_2              matlab.ui.control.Label
        OhmsLabel_3              matlab.ui.control.Label
        nHLabel                  matlab.ui.control.Label
        nHLabel_2                matlab.ui.control.Label
        S11S21plotDropDownLabel  matlab.ui.control.Label
        S11S21plotDropDown       matlab.ui.control.DropDown
    end

    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            
            r1 = [800 50];
            r2 = [800 50];
            r3 = [800 50];

            c1 = [1e-12 2.3e-11];
            c2 = [1e-12 2.3e-11];
            c3 = [1e-12 2.3e-11];
            c4 = [1e-12 2.3e-11];
            l1 = [1e-9 4.1e-8];
            l2 = [1e-9 4.1e-8];
    
            lamdag = 3/40; 
            tx1=rfckt.cpw('Height', 0.635e-3, 'EpsilonR', 10.8, 'ConductorWidth', 0.5e-3, 'LineLength', lamdag/2);
            tx2=rfckt.cpw('Height', 0.635e-3, 'EpsilonR', 10.8, 'ConductorWidth', 0.5e-3, 'LineLength', lamdag/4);
            f = 2e9:1.0e7:6e9;
            s = 2*pi*1i*f;

            analyze(tx1, f);
            analyze(tx2, f);
            sp1 = sparameters(tx1);
            sp2 = sparameters(tx2);
            tp1 = s2abcd(sp1.Parameters, 50);
            tp2 = s2abcd(sp2.Parameters, 50);

            halfa = 1:401;
            halfb = 1:401;
            halfc = 1:401;
            halfd = 1:401;

            for i = 1:length(f)
                halfa(i) = tp1(1,1,i);
            end
            for i = 1:length(f)
                halfb(i) = tp1(1,2,i);
            end
            for i = 1:length(f)
                halfc(i) = tp1(2,1,i);
            end
            for i = 1:length(f)
                halfd(i) = tp1(2,2,i);
            end

            quara = 1:401;
            quarb = 1:401;
            quarc = 1:401;
            quard = 1:401;
            for i = 1:length(f)
                quara(i) = tp2(1,1,i); 
            end
            for i = 1:length(f)
                quarb(i) = tp2(1,2,i); 
            end
            for i = 1:length(f)
                quarc(i) = tp2(2,1,i); 
            end
            for i = 1:length(f)
                quard(i) = tp2(2,2,i); 
            end

            if value(2) == 'n'
              app.C1EditField.Value = 23;
              app.C2EditField.Value = 23;
              app.C3EditField.Value = 23;
              app.C4EditField.Value = 23;
              app.R1EditField.Value = 50;
              app.R2EditField.Value = 50;
              app.R3EditField.Value = 50;
              app.L1EditField.Value = 41;
              app.L2EditField.Value = 41;
              app.LaserLamp.Color = [0 1 0];
              a = r1(2) + s .* l1(2);
              b = 1.0 ./ (s * c1(2));
              c = (s .* r3(2) .* c3(2) + 1) ./ (s .* s .* r3(2) .* c3(2) .* c4(2) + s .* c3(2) + s .* c4(2));

              q = r2(2) + s .* l2(2);
              h = 1.0 ./ (s * c2(2));

              A = (a ./ b) + (a + (q ./ b + 1) .* c) ./ q + 1;
              C = (c ./ b + 1) ./ q + 1 ./ b;
              D = c ./ b + h .* ((c ./ b + 1) ./ q + 1 ./ b) + 1;
              B = a + c .* (a ./ b + 1) + h .* (a ./ b + (a + c .* (a ./ b + 1)) ./ q + 1);

              zo = 50;

              para = zeros(1,length(f));
              parb = zeros(1,length(f));
              parc = zeros(1,length(f));
              pard = zeros(1,length(f));
              for i = 1 : length(f)
                  Q = [quara(i) quarb(i);quarc(i) quard(i)];
                  mul = [A(i) B(i);C(i) D(i)];
                  mul = Q * mul * Q;
                  para(i) = mul(1,1);
                  parb(i) = mul(1,2);
                  parc(i) = mul(2,1);
                  pard(i) = mul(2,2);
              end
              [ya, yb, yc, yd] = abcdtoy(para, parb, parc, pard);
              [yya, yyb, yyc, yyd] = abcdtoy(halfa, halfb, halfc, halfd);
              ya = ya + yya;
              yb = yb + yyb;
              yc = yc + yyc;
              yd = yd + yyd;
              [A, B, C, D] = ytoabcd(ya, yb, yc, yd);

              s11 = (A + B ./ zo - C .* zo - D) ./ (A + B ./ zo + C .* zo + D);
              s21 = 2 ./ (A + B ./ zo + C .* zo + D);
            else
              app.C1EditField.Value = 1;
              app.C2EditField.Value = 1;
              app.C3EditField.Value = 1;
              app.C4EditField.Value = 1;
              app.R1EditField.Value = 800;
              app.R2EditField.Value = 800;
              app.R3EditField.Value = 800;
              app.L1EditField.Value = 1;
              app.L2EditField.Value = 1;
              app.LaserLamp.Color = [1 0 0];
              a = r1(1) + s .* l1(1);
              b = 1.0 ./ (s * c1(1));
              c = (s .* r3(1) .* c3(1) + 1) ./ (s .* s .* r3(1) .* c3(1) .* c4(1) + s .* c3(1) + s .* c4(1));

              q = r2(1) + s .* l2(1);
              h = 1.0 ./ (s * c2(1));

              A = (a ./ b) + (a + (q ./ b + 1) .* c) ./ q + 1;
              C = (c ./ b + 1) ./ q + 1 ./ b;
              D = c ./ b + h .* ((c ./ b + 1) ./ q + 1 ./ b) + 1;
              B = a + c .* (a ./ b + 1) + h .* (a ./ b + (a + c .* (a ./ b + 1)) ./ q + 1);

              zo = 50;

              para = zeros(1,length(f));
              parb = zeros(1,length(f));
              parc = zeros(1,length(f));
              pard = zeros(1,length(f));
              for i = 1 : length(f)
                  Q = [quara(i) quarb(i);quarc(i) quard(i)];
                  mul = [A(i) B(i);C(i) D(i)];
                  mul = Q * mul * Q;
                  para(i) = mul(1,1);
                  parb(i) = mul(1,2);
                  parc(i) = mul(2,1);
                  pard(i) = mul(2,2);
              end
              [ya, yb, yc, yd] = abcdtoy(para, parb, parc, pard);
              [yya, yyb, yyc, yyd] = abcdtoy(halfa, halfb, halfc, halfd);
              ya = ya + yya;
              yb = yb + yyb;
              yc = yc + yyc;
              yd = yd + yyd;
              [A, B, C, D] = ytoabcd(ya, yb, yc, yd);

              s11 = (A + B ./ zo - C .* zo - D) ./ (A + B ./ zo + C .* zo + D);
              s21 = 2 ./ (A + B ./ zo + C .* zo + D);
            end
            if app.S11S21plotDropDown.Value == 'S11'
              title(app.UIAxes, 'S11 vs f')
              xlabel(app.UIAxes, 'Frequency in GHz')
              ylabel(app.UIAxes, 'S11 in dB')
              x = f;
              y = 200*log(s11);
              plot(app.UIAxes,x,y)
            else
              title(app.UIAxes, 'S21 vs f')
              xlabel(app.UIAxes, 'Frequency in GHz')
              ylabel(app.UIAxes, 'S21 in dB')
              x = f;
              y2 = log(s21);
              plot(app.UIAxes,x,y2)
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.LaserLampLabel = uilabel(app.UIFigure);
            app.LaserLampLabel.Text = 'Laser';
            app.LaserLamp = uilamp(app.UIFigure);
            app.OpticallyReconfigurableUWBBandpassfilterLabel = uilabel(app.UIFigure);
            app.OpticallyReconfigurableUWBBandpassfilterLabel.Text = 'Optically Reconfigurable UWB Bandpass filter';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Icon = 'circuit.PNG';
            app.Button.Text = '';
            app.C1EditFieldLabel = uilabel(app.UIFigure);
            app.C1EditFieldLabel.Text = 'C1';
            app.C1EditField = uieditfield(app.UIFigure, 'numeric');
            app.C2EditFieldLabel = uilabel(app.UIFigure);
            app.C2EditFieldLabel.Text = 'C2';
            app.C2EditField = uieditfield(app.UIFigure, 'numeric');
            app.C3EditFieldLabel = uilabel(app.UIFigure);
            app.C3EditFieldLabel.Text = 'C3';
            app.C3EditField = uieditfield(app.UIFigure, 'numeric');
            app.C4EditFieldLabel = uilabel(app.UIFigure);
            app.C4EditFieldLabel.Text = 'C4';
            app.C4EditField = uieditfield(app.UIFigure, 'numeric');
            app.R1EditFieldLabel = uilabel(app.UIFigure);
            app.R1EditFieldLabel.Text = 'R1';
            app.R1EditField = uieditfield(app.UIFigure, 'numeric');
            app.R2EditFieldLabel = uilabel(app.UIFigure);
            app.R2EditFieldLabel.Text = 'R2';
            app.R2EditField = uieditfield(app.UIFigure, 'numeric');
            app.R3EditFieldLabel = uilabel(app.UIFigure);
            app.R3EditFieldLabel.Text = 'R3';
            app.R3EditField = uieditfield(app.UIFigure, 'numeric');
            app.L1EditFieldLabel = uilabel(app.UIFigure);
            app.L1EditFieldLabel.Text = 'L1';
            app.L1EditField = uieditfield(app.UIFigure, 'numeric');
            app.L2EditFieldLabel = uilabel(app.UIFigure);
            app.L2EditFieldLabel.Text = 'L2';
            app.L2EditField = uieditfield(app.UIFigure, 'numeric');
            app.pFLabel = uilabel(app.UIFigure);
            app.pFLabel.Text = 'pF';
            app.pFLabel_2 = uilabel(app.UIFigure);
            app.pFLabel_2.Text = 'pF';
            app.pFLabel_3 = uilabel(app.UIFigure);
            app.pFLabel_3.Text = 'pF';
            app.pFLabel_4 = uilabel(app.UIFigure);
            app.pFLabel_4.Text = 'pF';
            app.OhmsLabel = uilabel(app.UIFigure);
            app.OhmsLabel.Text = 'Ohms';
            app.OhmsLabel_2 = uilabel(app.UIFigure);
            app.OhmsLabel_2.Text = 'Ohms';
            app.OhmsLabel_3 = uilabel(app.UIFigure);
            app.OhmsLabel_3.Text = 'Ohms';
            app.nHLabel = uilabel(app.UIFigure);
            app.nHLabel.Text = 'nH';
            app.nHLabel_2 = uilabel(app.UIFigure);
            app.nHLabel_2.Text = 'nH';
            app.S11S21plotDropDownLabel = uilabel(app.UIFigure);
            app.S11S21plotDropDownLabel.Text = 'S11/S21 plot';
            app.S11S21plotDropDown = uidropdown(app.UIFigure);
            app.S11S21plotDropDown.Items = {'S11', 'S12'};
            app.S11S21plotDropDown.Value = 'S11';
        end
    end

    methods (Access = public)

        % Construct app
        function app = raghavan

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end